classdef CalciumProjectApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        PatternRecognitionTab      matlab.ui.container.Tab
        Step1ChooseImagewithObviousLineButton  matlab.ui.control.Button
        PlayButton                 matlab.ui.control.Button
        Step3GetintensitiesButton  matlab.ui.control.Button
        right                      matlab.ui.control.Button
        left                       matlab.ui.control.Button
        Step2LoadFolderwithImagesButton  matlab.ui.control.Button
        ImageSlider                matlab.ui.control.Slider
        ImageLabel                 matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        ImageAdjustTab             matlab.ui.container.Tab
        LoadFileButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        myFolder   
        filePattern
        tifFiles   
        play
        means
        numImages
        playTimer
        finalX1
        finalY1
        finalX2
        finalY2
        Label

    end
    
    methods (Access = private)
        function displayImage(app, index)
            set(app.ImageSlider, 'value', index);
            file = app.tifFiles(round(index)); 
            filePath = fullfile(file.folder, file.name);
            imageArray = imread(filePath);
            app.Label.Text = file.name;
            app.ImageLabel.Text = round(index)+ "/" +app.numImages;
            
            global imageshown
            imageshown = imageArray;
            I = imshow(imageArray, 'Parent', app.UIAxes, ... 
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);
            
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            drawnow;
        end
        function [x1, y1, x2, y2] = LineDetection(app, imageFile)
            %imageFile = 'One Cell.tif';
            I = imageFile;
            I = im2gray(I(:,:,1));
            level = 0.99;
            BW = imbinarize(I,level);
            % imshowpair(I, BW, 'montage');
            [H,theta,rho] = hough(BW);
            P = houghpeaks(H,1,'threshold',ceil(0.3*max(H(:))));
            x = theta(P(:,2));
            y = rho(P(:,1));
            % plot(x,y,'s','color','black');
            lines = houghlines(BW,theta,rho,P,'FillGap',90,'MinLength',2.5);
            % figure
            % imshow(I), hold on
            if (~isempty(lines))
                xy = [lines(1).point1; lines(1).point2];
                %    plot(xy(:,1),xy(:,2),'LineWidth',2,'Color','green');
                %    plot(xy(1,1),xy(1,2),'x','LineWidth',2,'Color','yellow');
                %    plot(xy(2,1),xy(2,2),'x','LineWidth',2,'Color','red');
                x1 = xy(1,1);
                y1 = xy(1,2);
                x2 = xy(2,1);
                y2 = xy(2,2);
            else
                disp('No lines founded');
                x1 = 0;
                y1 = 0;
                x2 = 0;
                y2 = 0;
            end

        end
        function [weightedCellCentroid, cellCentroid] = cellCentroidLocate(app, imageFile)
            %imageFile = 'Cell 25s.tif';
            image = imread(imageFile);
            I_eq = adapthisteq(image);
            %imshow(I_eq)
            bw = imbinarize(I_eq, graythresh(I_eq));
            %imshow(bw)
            bw2 = imfill(bw,'holes');
            bw3 = imopen(bw2, ones(5,5));
            bw4 = bwareaopen(bw3, 40);
            %bw4_perim = bwperim(bw4);
            %overlay1 = imoverlay(I_eq, bw4_perim, [.3 1 .3]);
            %imshow(bw4)
            s = regionprops(bw4,image,{'Centroid','WeightedCentroid'});
            %figure, imshow(I)
            % title('Weighted (red) and Unweighted (blue) Centroids');
            % hold on
            numObj = numel(s);
            for k = 1 : numObj
                %plot(s(k).WeightedCentroid(1), s(k).WeightedCentroid(2), 'r*')
                weightedCellCentroid(k,:) = [s(k).WeightedCentroid(1), s(k).WeightedCentroid(2)];
                % plot(s(k).Centroid(1), s(k).Centroid(2), 'bo')
                cellCentroid(k,:) = [s(k).Centroid(1), s(k).Centroid(2)];
            end
        end  
        function cellCrop(app, fileName)
            centroidImage = imread(fileName);
            [sizeImage1,sizeImage2] = size(centroidImage);
            radius = 55;
            [tempWC, tempC] = app.cellCentroidLocate(fileName);
            [numCentroid, numCol] = size(tempC);
            currImage = imread(fileName);
            for ci = 1:numCentroid
                currC = round(tempC(ci,:));
                xMin = currC(1) - radius;
                xMax = currC(1) + radius;
                yMin = currC(2) - radius;
                yMax = currC(2) + radius;
                if xMin < 0 || xMax > sizeImage1 || yMin < 0 || yMax > sizeImage2
                    continue
                end
                currCell = currImage(yMin:yMax, xMin:xMax);
                [x1, y1, x2, y2] = app.LineDetection(currCell);
                app.finalX1(ci) = currC(1) - radius + x1;
                app.finalY1(ci) = currC(2) - radius + y1;
                app.finalX2(ci) = currC(1) - radius + x2;
                app.finalY2(ci) = currC(2) - radius + y2;
            end
        end

        function dispNext(app)
            if not(app.numImages)
                return;
            end
            
            y = min(app.ImageSlider.Value+1, app.numImages);
            displayImage(app, y);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.UIAxes,'DataAspectRatio',[2 2 1])
            app.play = false;
            
            app.numImages = 0;
            app.playTimer = timer('ExecutionMode', 'fixedRate', 'Period', 0.1, 'TimerFcn', @(~, ~) app.dispNext());
            try 
                rmdir 'Pattern Recognition'/tempFold s
            catch
                disp 'No folder to delete'
            end
            try
                rmdir 'Pattern Recognition'/updatedImages s
            catch 
                disp 'No folder to delete'
            end
            
        end

        % Button pushed function: Step2LoadFolderwithImagesButton
        function LoadFile(app, event)
            try
                app.myFolder = uigetdir('C:');  % get file
                
                figure(app.UIFigure); % focus window again
                app.filePattern = '.tif';
                app.tifFiles = dir([app.myFolder '/*' app.filePattern]);
                app.numImages = length(app.tifFiles); % takes length and adjusts the slider
                app.ImageSlider.Limits = [1 app.numImages];
                
                if not(app.numImages)
                    return;
                end
                
                set(app.ImageSlider, 'value', 1) % sets slider
                mkdir("Pattern Recognition/tempFold"); % temporary folder for storing images
                
                for i = 1:length(app.tifFiles)
                    image = [app.tifFiles(i).folder '/' app.tifFiles(i).name];
                    currFig = figure;
                    imshow(image), hold on
                    for ci = 1:length(app.finalX1)
                        plot([app.finalX1(ci), app.finalX2(ci)], [app.finalY1(ci), app.finalY2(ci)],'LineWidth',2,'Color','green');
                    end
                    hold off
                    saveas(gcf, ['Pattern Recognition/tempFold/' app.tifFiles(i).name])
                    close(currFig);
                end
                app.tifFiles = dir(['Pattern Recognition/tempFold/*' '.tif']);
                displayImage(app, 1);
            catch
                warning("There was an error in selecting your files. Please try again.");
            end
        end

        % Value changing function: ImageSlider
        function ImageSliderValueChanging(app, event)
            if not(app.numImages)
                return;
            end
            
            y = event.Value;
            displayImage(app, y);
        end

        % Button pushed function: left
        function leftPressed(app, event)
            if not(app.numImages)
                return;
            end
            
            y = max(app.ImageSlider.Value-1, 1);
            displayImage(app, y);
        end

        % Button pushed function: right
        function rightButtonPushed(app, event)
            if not(app.numImages)
                return;
            end
            
            y = min(app.ImageSlider.Value+1, app.numImages);
            displayImage(app, y);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.play = not(app.play);
            if app.play
                app.PlayButton.Text = 'Stop';
                start(app.playTimer);
                
            else
                app.PlayButton.Text = 'Play';
                stop(app.playTimer);
                
            end
            
            
        end

        % Button pushed function: Step3GetintensitiesButton
        function GetIntensities(app, event)
            % TODO - verify
            
            app.Label.Text = "Working";
            drawnow;
            
            files = dir(fullfile(app.myFolder + "\GFP", '*.tif')); % gets tif files from \GFP directory
            m = app.getMeans(files); 
            
            mkdir(app.myFolder+"\data", "ROI")
            outputFolder = app.myFolder + "\data\Results.csv";
            writematrix(m, outputFolder);
            
            if app.TOMCheckBox.Value
                files = dir(fullfile(app.myFolder + "\ToM", '*.tif')); % gets tif files from \GFP directory
                m = app.getMeans(files); 
                
                outputFolder = app.myFolder + "\data\ResultsTOM.csv";
                writematrix(m, outputFolder);
            end
            
            displayImage(app, app.ImageSlider.Value);
        end

        % Button pushed function: 
        % Step1ChooseImagewithObviousLineButton
        function Step1ChooseImagewithObviousLineButtonPushed(app, event)
            try 
                filterspec = '*.tif';
                [f, p] = uigetfile(filterspec);
                lineFile = [p f];
                app.cellCrop(lineFile);
            catch
                warning("There was an error in selecting your files. Please try again.")
            end
            
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            currFolder = uigetdir('C:');  % get file
            figure(app.UIFigure); % focus window again
            fileType = '.tif';
            allFiles = dir([currFolder '/*' fileType]);
            mkdir('Pattern Recognition/updatedImages');
            for i = 1:length(allFiles)
                image = imread([allFiles(i).folder '/' allFiles(i).name]);
                image = imadjust(image);
                imwrite(image, ['Pattern Recognition/updatedImages/' allFiles(i).name])
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PatternRecognitionTab = uitab(app.TabGroup);
            app.PatternRecognitionTab.Title = 'Pattern Recognition';
            app.UIAxes = uiaxes(app.PatternRecognitionTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ImageLabel = uilabel(app.PatternRecognitionTab);
            app.ImageLabel.Text = 'Image';
            app.ImageSlider = uislider(app.PatternRecognitionTab);
            app.ImageSlider.Limits = [0 200];
            app.ImageSlider.ValueChangingFcn = createCallbackFcn(app, @ImageSliderValueChanging, true);
            app.ImageSlider.Value = 1;
            app.Step2LoadFolderwithImagesButton = uibutton(app.PatternRecognitionTab, 'push');
            app.Step2LoadFolderwithImagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFile, true);
            app.Step2LoadFolderwithImagesButton.Text = {'Step 2: Load Folder with Images'; ''};
            app.left = uibutton(app.PatternRecognitionTab, 'push');
            app.left.ButtonPushedFcn = createCallbackFcn(app, @leftPressed, true);
            app.left.Text = '<';
            app.right = uibutton(app.PatternRecognitionTab, 'push');
            app.right.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.right.Text = '>';
            app.Step3GetintensitiesButton = uibutton(app.PatternRecognitionTab, 'push');
            app.Step3GetintensitiesButton.ButtonPushedFcn = createCallbackFcn(app, @GetIntensities, true);
            app.Step3GetintensitiesButton.Text = 'Step 3: Get intensities';
            app.PlayButton = uibutton(app.PatternRecognitionTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.Step1ChooseImagewithObviousLineButton = uibutton(app.PatternRecognitionTab, 'push');
            app.Step1ChooseImagewithObviousLineButton.ButtonPushedFcn = createCallbackFcn(app, @Step1ChooseImagewithObviousLineButtonPushed, true);
            app.Step1ChooseImagewithObviousLineButton.Text = 'Step 1: Choose Image with Obvious Line';
            app.ImageAdjustTab = uitab(app.TabGroup);
            app.ImageAdjustTab.Title = 'Image Adjust';
            app.LoadFileButton = uibutton(app.ImageAdjustTab, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalciumProjectApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end